classdef Clickcells < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        NextdefectButton          matlab.ui.control.Button
        DatafolderEditFieldLabel  matlab.ui.control.Label
        DatafolderEditField       matlab.ui.control.EditField
        SelectButton              matlab.ui.control.Button
        LoaddefectsButton         matlab.ui.control.Button
        NdefAx                    matlab.ui.control.UIAxes
        PdefAx                    matlab.ui.control.UIAxes
        DefectdoneButton          matlab.ui.control.Button
        LoadlabelsButton          matlab.ui.control.Button
        EditField                 matlab.ui.control.EditField
        SavelabelsButton          matlab.ui.control.Button
        ClearlabelsButton         matlab.ui.control.Button
        PlotdensityButton         matlab.ui.control.Button
        ResetviewButton           matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        CellsLabel                matlab.ui.control.Label
        DefectsLabel              matlab.ui.control.Label
        pnc                       matlab.ui.control.Label
        nnc                       matlab.ui.control.Label
        pnd                       matlab.ui.control.Label
        nnd                       matlab.ui.control.Label
        DensityDropDown           matlab.ui.control.DropDown
        BoxsizeLabel              matlab.ui.control.Label
        pbs                       matlab.ui.control.NumericEditField
        nbs                       matlab.ui.control.NumericEditField
        AllcellsLabel             matlab.ui.control.Label
        pnac                      matlab.ui.control.Label
        nnac                      matlab.ui.control.Label
        DefectIDEditFieldLabel    matlab.ui.control.Label
        DefectIDEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        defdefs % Description
        laserim % Description
        pts % Description
        labels % Description
        t % Description
        i % Description
        fpaths % Description
        boxline % Description
    end
    
    methods (Access = private)
        
        function updatepts(app)
            cff = app.defdefs(app.i).ff;
            if numel(app.labels)>0
                clabs = (([app.labels.ff] == cff).*([app.labels.t]==app.defdefs(app.i).ts)==1);
                
                xs = [app.labels(clabs).x];
                ys = [app.labels(clabs).y];
                cs = [app.labels(clabs).l];
                cmap = myxocmap;
                cmapsz = size(cmap);
                maxc = max(cs);
                minc = min(cs);
                
                if maxc~=minc
                    cs = round((cs-minc)*(cmapsz(1)-1)/(maxc-minc)+1);
                else
                    cs = ones(size(cs));
                end
                
                cs = cmap(cs,:);
                
                set(app.pts,'XData',xs,'YData',ys,'CData',cs);
            else
                set(app.pts,'XData',[],'YData',[],'CData',[]);
            end
            
%             app.pts.XData = xs;
%             app.pts.YData = ys;
%             app.pts.CData = cs;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.laserim = imagesc(app.UIAxes,zeros(768,1024));
            hold(app.UIAxes,'on');
            app.pts = scatter(app.UIAxes,[],[],20,[],'filled','MarkerEdgeColor','k');
            app.boxline = plot(app.UIAxes,[1 2 400], [1 2 400],'g','LineWidth',1);
            set(app.UIAxes,'colormap',gray);

            try
                app.fpaths = getfold(app.DatafolderEditField.Value);
            end

        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            fold = uigetdir;
            app.fpathEditField.Value = fold;
            app.fpaths = getfold(fold);
        end

        % Button pushed function: LoaddefectsButton
        function LoaddefectsButtonPushed(app, event)
            app.defdefs = [];
            for f = 1:numel(app.fpaths)
                load([app.fpaths{f} 'adefs.mat'],'adefs');
                for ci = 1:numel(adefs)
                    adefs(ci).ff = f;
                    adefs(ci).done = false;
                end
                app.defdefs = [app.defdefs; adefs];
            end
            
            app.i = randi(numel(app.defdefs));
            
            l = laserdata(app.fpaths{app.defdefs(app.i).ff}, app.defdefs(app.i).ts);
            l = l./imgaussfilt(l,64);
            l = normalise(l);
            app.laserim.CData = l;
            set(app.UIAxes,'XLim', [app.defdefs(app.i).x-200 app.defdefs(app.i).x+200]);
            set(app.UIAxes,'YLim', [app.defdefs(app.i).y-200 app.defdefs(app.i).y+200]);
            thets = 0:2*pi/200:2*pi;
            circx = 200*cos(thets) + app.defdefs(ci).x;
            circy = 200*sin(thets) + app.defdefs(ci).y;
            
            set(app.boxline, 'XData', circx,'YData',circy)
        end

        % Button pushed function: NextdefectButton
        function NextdefectButtonPushed(app, event)
            ci = randi(numel(app.defdefs));
            app.i = ci;
            l = laserdata(app.fpaths{app.defdefs(ci).ff}, app.defdefs(ci).ts);
            l = l./imgaussfilt(l,64);
            l = normalise(l);
            app.laserim.CData = l;
            set(app.UIAxes,'XLim', [app.defdefs(ci).x-200 app.defdefs(ci).x+200]);
            set(app.UIAxes,'YLim', [app.defdefs(ci).y-200 app.defdefs(ci).y+200]);
            thets = 0:2*pi/200:2*pi;
            circx = 200*cos(thets) + app.defdefs(ci).x;
            circy = 200*sin(thets) + app.defdefs(ci).y;
            
            set(app.boxline, 'XData', circx,'YData',circy)
            updatepts(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            lab = str2double(key);
            C = get(app.UIAxes,'CurrentPoint');
            
            if ~isnan(lab)
                app.labels(end+1).x = C(1,1);
                app.labels(end).y = C(1,2);
                app.labels(end).l = round(lab);
                app.labels(end).t = app.defdefs(app.i).ts;
                app.labels(end).ff = app.defdefs(app.i).ff;
                app.labels(end).def_i = app.i;
                updatepts(app);
                drawnow
            elseif key == 'd'
                ctime = ([app.labels.ff] == app.defdefs(app.i).ff)...
                    .*([app.labels.t] == app.defdefs(app.i).ts)==1;
                
                dxs = [app.labels.x] - C(1,1);
                dys = [app.labels.y] - C(1,2);
                dists = sqrt(dxs.^2+dys.^2);
                
                closest = dists == min(dists(ctime));
                app.labels(closest) = [];
                updatepts(app)

            end
                        
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
        end

        % Button pushed function: DefectdoneButton
        function DefectdoneButtonPushed(app, event)
            app.defdefs(app.i).done = true;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: SavelabelsButton
        function SavelabelsButtonPushed(app, event)
            labs = app.labels;
            defs = app.defdefs;
            ci = app.i;
            save([app.DatafolderEditField.Value '/' sprintf('%s.mat',app.EditField.Value)],'labs','defs','ci');
            
        end

        % Button pushed function: LoadlabelsButton
        function LoadlabelsButtonPushed(app, event)
            load([app.DatafolderEditField.Value '/' sprintf('%s.mat',app.EditField.Value)],'labs','defs','ci');
            app.labels = labs;
            app.defdefs = defs;
            try 
                app.i = ci;
            catch
                ci = randi(numel(defs));
                app.i = ci;
            end
            l = laserdata(app.fpaths{app.defdefs(ci).ff}, app.defdefs(ci).ts);
            l = l./imgaussfilt(l,64);
            l = normalise(l);
            app.laserim.CData = l;
            set(app.UIAxes,'XLim', [app.defdefs(ci).x-200 app.defdefs(ci).x+200]);
            set(app.UIAxes,'YLim', [app.defdefs(ci).y-200 app.defdefs(ci).y+200]);
            thets = 0:2*pi/200:2*pi;
            circx = 200*cos(thets) + app.defdefs(ci).x;
            circy = 200*sin(thets) + app.defdefs(ci).y;
            
            set(app.boxline, 'XData', circx,'YData',circy)
            updatepts(app);
        end

        % Button pushed function: ClearlabelsButton
        function ClearlabelsButtonPushed(app, event)
            app.labels = [];
            updatepts(app);
        end

        % Button pushed function: PlotdensityButton
        function PlotdensityButtonPushed(app, event)
            donedefs = find([app.defdefs.done]);
            cla(app.PdefAx);
            cla(app.NdefAx);
            hold(app.PdefAx,'on');
            hold(app.NdefAx,'on');
            
            npds = 0;
            nnds = 0;
            npcs = 0;
            nncs = 0;
            npacs = 0;
            nnacs = 0;
            szp = ceil(242/app.pbs.Value);
            szn = ceil(242/app.nbs.Value);
            totp = zeros(szp);
            totn = zeros(szn);
            
            totpall = zeros(242);
            totnall = zeros(242);
            
            for def_is = donedefs
                clabs = app.labels(([app.labels.t] == app.defdefs(def_is).ts)...
                    .*([app.labels.ff]==app.defdefs(def_is).ff)==1);
                
                cxs = [clabs.x];
                cys = [clabs.y];
                relx = app.defdefs(def_is).x-cxs;
                rely = app.defdefs(def_is).y-cys;
                labes = [clabs.l];
                
                relrs = sqrt(relx.^2+rely.^2);
                labthetas = atan2(rely,relx);
                defdir = atan2(app.defdefs(def_is).d(2),app.defdefs(def_is).d(1));
                relthetas = defdir-labthetas;
                relxs = relrs.*cos(relthetas);
                relys = relrs.*sin(relthetas);
                relxs2 = relrs.*cos(relthetas + 2*pi/3);
                relys2 = relrs.*sin(relthetas + 2*pi/3);
                relxs3 = relrs.*cos(relthetas + 4*pi/3);
                relys3 = relrs.*sin(relthetas + 4*pi/3);
                
                if app.defdefs(def_is).q > 0
                    npds = npds+1;
                    npcs = npcs+sum(relrs<200);
                    npacs = npacs+numel(relrs);
                    

                    if app.DensityDropDown.Value == "Points"
                        plot(app.PdefAx,relxs,relys,'.k');
                    elseif app.DensityDropDown.Value == "Density"
                        recenterx = relxs+141;
                        recentery = relys+141;
                        
                        del = ((recenterx<0)+(recentery<0)+(recenterx>242)+(recentery>242)) > 0;
                        recenterx(del) = [];
                        recentery(del) = [];
                        labes(del) = [];
                        
                        subx = ceil(recenterx/app.pbs.Value);
                        suby = ceil(recentery/app.pbs.Value);
                        
                        subs = sub2ind([szp szp],suby,subx);
                        
                        totes = accumarray(subs',labes',[],@sum);
                        totesub = accumarray(subs',subs',[],@mean);
                        totes(totesub==0) = [];
                        totesub(totesub==0) = [];
                        totp(totesub) = totp(totesub)+totes;
                    elseif app.DensityDropDown.Value == "Density All"
                        
                        
                    end
                        
                    
                elseif app.defdefs(def_is).q < 0
                    nnds = nnds+1;
                    nncs = (nncs+sum(relrs<200));
                    nnacs = (nnacs+numel(relrs));
                    
                    subx = [round(relxs/app.nbs.Value) round(relxs2/app.nbs.Value) round(relxs3/app.nbs.Value)];
                    suby = [round(relys/app.nbs.Value) round(relys2/app.nbs.Value) round(relys3/app.nbs.Value)];
                    
                    
                    if app.DensityDropDown.Value == "Points"
                        plot(app.NdefAx,relxs,relys,'.k');
                        plot(app.NdefAx,relxs2,relys2,'.k');
                        plot(app.NdefAx,relxs3,relys3,'.k');
                    elseif app.DensityDropDown.Value == "Density"
                        recenterx = relxs+141;
                        recentery = relys+141;
                        
                        del = ((recenterx<0)+(recentery<0)+(recenterx>242)+(recentery>242)) > 0;
                        recenterx(del) = [];
                        recentery(del) = [];
                        labes(del) = [];
                        
                        subx = ceil(recenterx/app.nbs.Value);
                        suby = ceil(recentery/app.nbs.Value);
                        
                        subs = sub2ind([szn szn],suby,subx);
                        
                        totes = accumarray(subs',labes',[],@sum);
                        totesub = accumarray(subs',subs',[],@mean);
                        totes(totesub==0) = [];
                        totesub(totesub==0) = [];
                        totn(totesub) = totn(totesub)+totes;
                    elseif app.DensityDropDown.Value == "Density All"
                        
                    end
                        
                end
            end
            app.PdefAx.XLim = [-141 141];
            app.NdefAx.XLim = [-141 141];
            app.PdefAx.YLim = [-141 141];
            app.NdefAx.YLim = [-141 141];
            if app.DensityDropDown.Value == "Density"
                imagesc(app.PdefAx,totp/npds);
                imagesc(app.NdefAx,totn/nnds);
                app.PdefAx.XLim = [0 szp];
                app.PdefAx.YLim = [0 szp];
                app.NdefAx.XLim = [0 szn];
                app.NdefAx.YLim = [0 szn];
                app.PdefAx.CLim = [0 max(totp(:)/npds)];
                app.NdefAx.CLim = [0 max(totn(:)/nnds)];
            end
            app.nnc.Text = num2str(nncs);
            app.pnc.Text = num2str(npcs);
            app.nnac.Text = num2str(nnacs);
            app.pnac.Text = num2str(npacs);
            app.nnd.Text = num2str(nnds);
            app.pnd.Text = num2str(npds);
        end

        % Button pushed function: ResetviewButton
        function ResetviewButtonPushed(app, event)
            set(app.UIAxes,'XLim', [app.defdefs(app.i).x-200 app.defdefs(app.i).x+200]);
            set(app.UIAxes,'YLim',[app.defdefs(app.i).y-200 app.defdefs(app.i).y+200]);
            
        end

        % Value changed function: DefectIDEditField
        function DefectIDEditFieldValueChanged(app, event)
            ci = app.DefectIDEditField.Value;
            app.i = ci;
            
            l = laserdata(app.fpaths{app.defdefs(app.i).ff}, app.defdefs(app.i).ts);
            l = l./imgaussfilt(l,64);
            l = normalise(l);
            app.laserim.CData = l;
            set(app.UIAxes,'XLim', [app.defdefs(app.i).x-200 app.defdefs(app.i).x+200]);
            set(app.UIAxes,'YLim', [app.defdefs(app.i).y-200 app.defdefs(app.i).y+200]);
            thets = 0:2*pi/200:2*pi;
            circx = 200*cos(thets) + app.defdefs(ci).x;
            circy = 200*sin(thets) + app.defdefs(ci).y;
            
            set(app.boxline, 'XData', circx,'YData',circy)
            
            updatepts(app)
            drawnow
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.NextdefectButton = uibutton(app.UIFigure, 'push');
            app.NextdefectButton.ButtonPushedFcn = createCallbackFcn(app, @NextdefectButtonPushed, true);
            app.NextdefectButton.Text = 'Next defect';
            app.DatafolderEditFieldLabel = uilabel(app.UIFigure);
            app.DatafolderEditFieldLabel.Text = 'Data folder';
            app.DatafolderEditField = uieditfield(app.UIFigure, 'text');
            app.DatafolderEditField.Value = '/Users/kcopenhagen/Documents/Data/Monolayer/High frame rate/Data';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.LoaddefectsButton = uibutton(app.UIFigure, 'push');
            app.LoaddefectsButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddefectsButtonPushed, true);
            app.LoaddefectsButton.Text = 'Load defects';
            app.NdefAx = uiaxes(app.UIFigure);
            title(app.NdefAx, '')
            xlabel(app.NdefAx, '')
            ylabel(app.NdefAx, '')
            app.NdefAx.XTick = [];
            app.NdefAx.YTick = [];
            app.NdefAx.Visible = 'off';
            app.PdefAx = uiaxes(app.UIFigure);
            title(app.PdefAx, '')
            xlabel(app.PdefAx, '')
            ylabel(app.PdefAx, '')
            app.PdefAx.XTick = [];
            app.PdefAx.YTick = [];
            app.PdefAx.Visible = 'off';
            app.DefectdoneButton = uibutton(app.UIFigure, 'push');
            app.DefectdoneButton.ButtonPushedFcn = createCallbackFcn(app, @DefectdoneButtonPushed, true);
            app.DefectdoneButton.Text = 'Defect done';
            app.LoadlabelsButton = uibutton(app.UIFigure, 'push');
            app.LoadlabelsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadlabelsButtonPushed, true);
            app.LoadlabelsButton.Text = 'Load labels';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'labeled_cells';
            app.SavelabelsButton = uibutton(app.UIFigure, 'push');
            app.SavelabelsButton.ButtonPushedFcn = createCallbackFcn(app, @SavelabelsButtonPushed, true);
            app.SavelabelsButton.Text = 'Save labels';
            app.ClearlabelsButton = uibutton(app.UIFigure, 'push');
            app.ClearlabelsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlabelsButtonPushed, true);
            app.ClearlabelsButton.Text = 'Clear labels';
            app.PlotdensityButton = uibutton(app.UIFigure, 'push');
            app.PlotdensityButton.ButtonPushedFcn = createCallbackFcn(app, @PlotdensityButtonPushed, true);
            app.PlotdensityButton.Text = 'Plot density';
            app.ResetviewButton = uibutton(app.UIFigure, 'push');
            app.ResetviewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetviewButtonPushed, true);
            app.ResetviewButton.Text = 'Reset view';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '+1/2: ';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '-1/2:';
            app.CellsLabel = uilabel(app.UIFigure);
            app.CellsLabel.Text = 'Cells';
            app.DefectsLabel = uilabel(app.UIFigure);
            app.DefectsLabel.Text = 'Defects';
            app.pnc = uilabel(app.UIFigure);
            app.pnc.Text = '0';
            app.nnc = uilabel(app.UIFigure);
            app.nnc.Text = '0';
            app.pnd = uilabel(app.UIFigure);
            app.pnd.Text = '0';
            app.nnd = uilabel(app.UIFigure);
            app.nnd.Text = '0';
            app.DensityDropDown = uidropdown(app.UIFigure);
            app.DensityDropDown.Items = {'Points', 'Density All', 'Density'};
            app.DensityDropDown.Value = 'Points';
            app.BoxsizeLabel = uilabel(app.UIFigure);
            app.BoxsizeLabel.Text = 'Box size';
            app.pbs = uieditfield(app.UIFigure, 'numeric');
            app.pbs.Value = 20;
            app.nbs = uieditfield(app.UIFigure, 'numeric');
            app.nbs.Value = 10;
            app.AllcellsLabel = uilabel(app.UIFigure);
            app.AllcellsLabel.Text = 'All cells';
            app.pnac = uilabel(app.UIFigure);
            app.pnac.Text = '0';
            app.nnac = uilabel(app.UIFigure);
            app.nnac.Text = '0';
            app.DefectIDEditFieldLabel = uilabel(app.UIFigure);
            app.DefectIDEditFieldLabel.Text = 'Defect ID:';
            app.DefectIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.DefectIDEditField.ValueChangedFcn = createCallbackFcn(app, @DefectIDEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Clickcells

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end