classdef Corrections < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        fpathEditFieldLabel  matlab.ui.control.Label
        fpathEditField       matlab.ui.control.EditField
        SelectButton         matlab.ui.control.Button
        TimeEditFieldLabel   matlab.ui.control.Label
        TimeEditField        matlab.ui.control.NumericEditField
        LoadButton           matlab.ui.control.Button
        OverwriteButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        Layers % Description
        fpath % Description
        time % Description
        layerim % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            fold = uigetdir;
            app.fpathEditField.Value = fold;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.fpath = [app.fpathEditField.Value '/'];
            app.time = app.TimeEditField.Value;
            app.Layers = loaddata(app.fpath,app.time,'covid_layers','int8');
            l = laserdata(app.fpath,app.time);
            l = normalise(l./imgaussfilt(l,64));
            im = real2rgb(app.Layers,myxocmap,[0 2]);
            im = im.*l;
            imshow(im,'Parent',app.UIAxes);
            set(app.UIAxes,'XLim',[1 1024],'YLim',[1 768]);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            l = str2double(key);
            C = get(app.UIAxes,'CurrentPoint');
            
            if ~isnan(l)
                x = C(1,1);
                y = C(1,2);
                newish = zeros(size(app.Layers));
                newish(round(y),round(x)) = 1;
                newish = imdilate(newish,strel('disk',8));
                app.Layers(newish==1) = l;
                cla(app.UIAxes);
                im = real2rgb(app.Layers,myxocmap,[0 2]);
                l = laserdata(app.fpath,app.time);
                l = normalise(l./imgaussfilt(l,64));
                im = im.*l;
                imshow(im,'Parent',app.UIAxes);
                drawnow
                
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
        end

        % Button pushed function: OverwriteButton
        function OverwriteButtonPushed(app, event)
            name = sprintf('%06d.bin',app.time-1);
                
            fID = fopen([app.fpath 'analysis/covid_layers/' name],'w');
            fwrite(fID,app.Layers,'int8');
            fclose(fID);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.fpathEditFieldLabel = uilabel(app.UIFigure);
            app.fpathEditFieldLabel.Text = 'fpath';
            app.fpathEditField = uieditfield(app.UIFigure, 'text');
            app.fpathEditField.Value = '/Users/kcopenhagen/Documents/Data/Monolayer/High frame rate/Data/190111KC2';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.TimeEditFieldLabel = uilabel(app.UIFigure);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.OverwriteButton = uibutton(app.UIFigure, 'push');
            app.OverwriteButton.ButtonPushedFcn = createCallbackFcn(app, @OverwriteButtonPushed, true);
            app.OverwriteButton.Text = 'Overwrite';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Corrections

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end