classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        rawheightplot              matlab.ui.control.UIAxes
        processeddataplot          matlab.ui.control.UIAxes
        SelectButton               matlab.ui.control.Button
        LocationEditField          matlab.ui.control.EditField
        TimeSlider                 matlab.ui.control.Slider
        RawCbar                    matlab.ui.control.UIAxes
        ProcCbar                   matlab.ui.control.UIAxes
        Panel                      matlab.ui.container.Panel
        rawmin                     matlab.ui.control.EditField
        rawmax                     matlab.ui.control.EditField
        procmin                    matlab.ui.control.EditField
        procmax                    matlab.ui.control.EditField
        RawLabel                   matlab.ui.control.Label
        ProcessedLabel             matlab.ui.control.Label
        DisplaysettingsLabel       matlab.ui.control.Label
        autoraw                    matlab.ui.control.Button
        autoproc                   matlab.ui.control.Button
        ColormapEditFieldLabel     matlab.ui.control.Label
        ColormapEditField          matlab.ui.control.EditField
        ShowDropDownLabel          matlab.ui.control.Label
        ShowDropDown               matlab.ui.control.DropDown
        OutlinesCheckBox           matlab.ui.control.CheckBox
        HeightlimitsmLabel         matlab.ui.control.Label
        HeightmapsurfacecorrectionLabel  matlab.ui.control.Label
        Panel_2                    matlab.ui.container.Panel
        ExportdataLabel            matlab.ui.control.Label
        NameEditFieldLabel         matlab.ui.control.Label
        NameEditField              matlab.ui.control.EditField
        DataDropDownLabel          matlab.ui.control.Label
        DataDropDown               matlab.ui.control.DropDown
        ExportButton               matlab.ui.control.Button
        PlayButton                 matlab.ui.control.StateButton
        AutopointsButton           matlab.ui.control.Button
        Panel_4                    matlab.ui.container.Panel
        ProcessdataButton          matlab.ui.control.Button
        ProcessingBar              matlab.ui.control.Image
        ProcessingpleasewaitLabel  matlab.ui.control.Label
        Panel_5                    matlab.ui.container.Panel
        LoaddataButton             matlab.ui.control.Button
        LoadingBar                 matlab.ui.control.Image
        LoadingpleasewaitLabel     matlab.ui.control.Label
        SmoothLabel                matlab.ui.control.Label
        SigmaEdit                  matlab.ui.control.NumericEditField
        TimePanel                  matlab.ui.container.Panel
        time_edit                  matlab.ui.control.EditField
        FindingpointsLabel         matlab.ui.control.Label
        Panel_6                    matlab.ui.container.Panel
        RawheightCheckBox          matlab.ui.control.CheckBox
        LaserOverlayLabel          matlab.ui.control.Label
        ProcesseddataCheckBox      matlab.ui.control.CheckBox
        Label                      matlab.ui.control.Label
        laseralpha                 matlab.ui.control.NumericEditField
        to1Label                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        hdataraw % Raw loaded height data.
        hdataproc % Processed height data.
        labels % Labeled points.
        baseline % Interpolated zero height plane.
        layers % Layer map.
        threshs % Threholds for each time point.
        pts % Scatter plot of labeled points.
        rawhim % Image of raw height being displayed.
        prochim % Image of processed data being displayed.
        processed % Keeps track of whether processing has happened or not.
        t % Current time.
        load_bar_im % Image displayed the loading bar.
        proc_bar_im % Image displayed in processing bar.
    end
    
    methods (Access = private)
        function plotraw(app)
            
            app.rawhim.CData = app.hdataraw{app.t};
            if (app.RawheightCheckBox.Value)
                l = laserdata(app.LocationEditField.Value,app.t);
                l = l./imgaussfilt(l,64);
                l = normalise(l);
                l = app.laseralpha.Value*l+(1-app.laseralpha.Value);
                app.rawhim.AlphaData = l;
            else
                app.rawhim.AlphaData = 1;
            end
            if numel(app.labels)>0
                updatepts(app)
            end
            
        end
        
        function plotproc(app)
            if app.processed
                if numel(app.labels)>0
                    if app.ShowDropDown.Value == "Zero height"
                        app.prochim.CData = app.baseline{app.t};
                    elseif app.ShowDropDown.Value == "Processed height"
                        app.prochim.CData = app.hdataproc{app.t};
                    elseif app.ShowDropDown.Value == "Layers"
                        app.prochim.CData = app.layers{app.t};
                    end
                    if app.OutlinesCheckBox.Value
                        layeds = imdilate(edge(app.layers{app.t}),strel('disk',3));
                        app.prochim.AlphaData = ~layeds;
                    else
                        app.prochim.AlphaData = 1;
                    end
                    if (app.ProcesseddataCheckBox.Value)
                        l = laserdata(app.LocationEditField.Value,app.t);
                        l = l./imgaussfilt(l,64);
                        l = normalise(l);
                        l = app.laseralpha.Value*l+(1-app.laseralpha.Value);
                        app.prochim.AlphaData = l;
                    else
                        app.prochim.AlphaData = 1;
                    end
                end
                app.ProcCbar.XTickLabel = [0; ...
                    str2double(sprintf('%1.2f',0.25*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                    str2double(sprintf('%1.2f',0.5*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                    str2double(sprintf('%1.2f',0.75*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                    str2double(sprintf('%1.2f',(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1))))];
            end
        end
        
        function updatepts(app)
            
            xs = [app.labels([app.labels.t]==app.t).x];
            ys = [app.labels([app.labels.t]==app.t).y];
            cs = [app.labels([app.labels.t]==app.t).l];
            cmap = feval(app.ColormapEditField.Value);
            cmapsz = size(cmap);
            maxc = max(cs);
            minc = min(cs);
            
            if maxc~=minc
                cs = round((cs-minc)*(cmapsz(1)-1)/(maxc-minc)+1);
            else
                cs = ones(size(cs));
            end
            
            cs = cmap(cs,:);
            app.pts.XData = xs;
            app.pts.YData = ys;
            app.pts.CData = cs;
        end
        
        function new_t(app, tt)
            if tt<app.TimeSlider.Limits(1)
                tt = app.TimeSlider.Limits(1);
            elseif tt>app.TimeSlider.Limits(2)
                tt = app.TimeSlider.Limits(2);
            end
            app.t = tt;
            app.TimeSlider.Value = tt;
            app.time_edit.Value = num2str(tt);
            plotraw(app)
            plotproc(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t = 1;
            
            app.processed = false;
            app.rawhim = imagesc(app.rawheightplot,zeros(768,1024));
            hold(app.rawheightplot,'on');
            app.pts = scatter(app.rawheightplot,[],[],20,[],'filled','MarkerEdgeColor','k');
            app.prochim = imagesc(app.processeddataplot,zeros(768,1024));
            hold(app.processeddataplot,'on');

            app.load_bar_im = zeros(10,95,3);
            app.proc_bar_im = zeros(10,95,3);
            app.load_bar_im(:,:,1) = 1;
            app.proc_bar_im(:,:,1) = 1;
            app.LoadingBar.ImageSource = app.load_bar_im;
            app.ProcessingBar.ImageSource = app.proc_bar_im;
            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            fold = uigetdir;
            app.LocationEditField.Value = fold;
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            
            app.hdataraw = [];
            app.rawheightplot.Colormap = feval(app.ColormapEditField.Value);
            app.processeddataplot.Colormap = feval(app.ColormapEditField.Value);
            %Make colorbars
            x = 1:512;
            y = 1:20;
            [xx,~] = meshgrid(x,y);
            cbar = real2rgb(xx,feval(app.ColormapEditField.Value),[1 512]);

            image(app.RawCbar,cbar);
            image(app.ProcCbar,cbar);
            app.LoadingpleasewaitLabel.Visible = 'on';
            drawnow
            fpath = [app.LocationEditField.Value '/'];
            ts = getts(fpath);
            x = 1:1024;
            y = 1:768;
            [xx,yy] = meshgrid(x,y);
            totp = numel(ts);
            for tt = 1:numel(ts)
                
                h = heightdata(fpath,tt);
                
                fitsurface = fit([xx(:), yy(:)],h(:),'poly11');                
                corr1 = fitsurface(xx,yy);
                h = h - corr1;
                sig = app.SigmaEdit.Value;
                if sig>0
                    h = imgaussfilt(h,sig);
                end
                
                app.hdataraw{tt} = h;

                fracp = tt/totp;
                fraci = round(fracp*95);
                app.load_bar_im(:,1:fraci,1) = 0;
                app.load_bar_im(:,1:fraci,2) = 1;
                app.LoadingBar.ImageSource = app.load_bar_im;
                drawnow
            end
            app.TimeSlider.Limits = [1 numel(ts)];
            app.autorawButtonPushed(app);
            plotraw(app)
            app.LoadingpleasewaitLabel.Visible = 'off';

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            l = str2double(key);
            C = get(app.rawheightplot,'CurrentPoint');
            drs = [];
            if ~isnan(l)
                if numel(app.labels)>0
                    clabels = app.labels([app.labels.t]==app.t);
                    if numel(clabels)>0
                        dxs = [clabels.x]-C(1,1);
                        dys = [clabels.y]-C(1,2);
                        drs = sqrt(dxs.^2+dys.^2);
                    end
                else
                    drs = 50;
                end
                if isempty(drs)
                    drs = 100;
                end
                if min(drs)>20
                    app.labels(end+1).x = C(1,1);
                    app.labels(end).y = C(1,2);
                    app.labels(end).l = round(l);
                    app.labels(end).t = app.t;
                    updatepts(app)
                end
                app.proc_bar_im(:,:,1) = 1;
                app.proc_bar_im(:,:,2) = 0;
                app.ProcessingBar.ImageSource = app.proc_bar_im;
            elseif key == 'd'
                ctime = [app.labels.t] == app.t;
                dxs = [app.labels.x] - C(1,1);
                dys = [app.labels.y] - C(1,2);
                dists = sqrt(dxs.^2+dys.^2);
                
                closest = dists == min(dists(ctime));
                app.labels(closest) = [];
                updatepts(app)
                app.proc_bar_im(:,:,1) = 1;
                app.proc_bar_im(:,:,2) = 0;
                app.ProcessingBar.ImageSource = app.proc_bar_im;
            elseif key == "leftarrow"
                app.new_t(app.t-1);
            elseif key == "rightarrow"
                app.new_t(app.t+1);
            end

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
        end

        % Button pushed function: ProcessdataButton
        function ProcessdataButtonPushed(app, event)
            if numel(app.labels) < 10
                return;
            end
            app.ProcessingpleasewaitLabel.Visible = 'on';
            drawnow
            x = 1:1024;
            y = 1:768;
            [xq,yq] = meshgrid(x,y);
            ts = unique([app.labels.t]);
            totp = numel(ts)+numel(app.hdataraw)*2;
            
            for tt = ts
                
                clabels = app.labels([app.labels.t]==tt);
                ls = unique([clabels.l]);

                basel = min(ls);
                hs = app.hdataraw{tt};
                clays = 0;
                for l = ls(ls>basel)
                    
                    cllabels = clabels([clabels.l]==l);
                    pllabels = clabels([clabels.l]==l-1);
                    
                    inds = sub2ind(size(xq),round([cllabels.y]),round([cllabels.x]));
                    pinds = sub2ind(size(xq),round([pllabels.y]),round([pllabels.x]));
                    ch = hs(inds);
                    ph = hs(pinds);
                    
                    dx = [cllabels.x]-[pllabels.x]';
                    dy = [cllabels.y]-[pllabels.y]';
                    dh = ch - ph';
                    
                    dr = sqrt(dx.^2+dy.^2);
                    sz = size(dr);
                    
                    [mins,rind] = min(dr,[],1);
                    rind(mins>50) = [];
                    cind = 1:sz(2);
                    cind(mins>50) = [];
                    inds = sub2ind(sz,rind,cind);
                    dhs = dh(inds);
                    
                    [mins,cind] = min(dr,[],2);
                    cind(mins>50) = [];
                    cind = cind';
                    rind = 1:sz(1);
                    rind(mins>50) = [];
                    inds = sub2ind(sz,rind,cind);
                    
                    dhs = [dhs dh(inds)];
                    
                    clays = [clays; sum(clays) + mean(dhs,'all')];
                end
                
                axs = [clabels.x];
                ays = [clabels.y];
                ls = [clabels.l];
                inds = sub2ind(size(xq),round([clabels.y]),round([clabels.x]));
                hs = app.hdataraw{tt};
                clays = clays';
                ahs = hs(inds)-clays(ls-basel+1);
                
                if numel(clays)>1
                    app.threshs{tt} = conv(clays,[0.5 0.5],'valid');
                else
                    app.threshs{tt} = -Inf;
                end
                
                app.baseline{tt} = griddata(axs,ays,ahs,xq,yq,'v4');
                fracp = tt/totp;
                fraci = round(fracp*95);
                app.proc_bar_im(:,1:fraci,1) = 0;
                app.proc_bar_im(:,1:fraci,2) = 1;
                app.ProcessingBar.ImageSource = app.proc_bar_im;
                drawnow
                
            end
            for tt = 1:numel(app.hdataraw)
                t1 = max(ts(ts<=tt));
                t2 = min(ts(ts>tt));
                
                if isempty(t1)
                    app.baseline{tt} = app.baseline{t2};
                    app.threshs{tt} = app.threshs{t2};
                elseif isempty(t2)
                    app.baseline{tt} = app.baseline{t1};
                    app.threshs{tt} = app.threshs{t1};
                else
                    p2 = (tt-t1)/(t2-t1);
                    p1 = 1-p2;

                    thresh1 = app.threshs{t1};
                    thresh2 = app.threshs{t2};
                    
                    An = numel(thresh1);
                    Bn = numel(thresh2);
                    dn = Bn - An;
                    if dn~=0
                        if dn<0
                            B1 = thresh2;
                            A1 = thresh1;
                        elseif dn>0
                            B1 = thresh1;
                            A1 = thresh2;
                        end
                        
                        minb = 100;
                        minbd = 1000;
                        for b = 1:abs(dn)+1
                            Bt = NaN(1,max(An,Bn));
                            Bt(b:b+min(An,Bn)-1) = B1;
                            diff = Bt-A1;
                            if sum(diff.^2,'omitnan')<minbd
                                minb = b;
                                minbd = sum(diff.^2,'omitnan');
                            end
                        end
                        
                        Bt = NaN(1,numel(A1));
                        Bt(minb:minb+numel(B1)-1) = B1;
                        Bt(isnan(Bt)) = A1(isnan(Bt));
                        if dn<0
                            thresh2 = Bt;
                        else
                            thresh1 = Bt;
                        end
                    end

                    app.baseline{tt} = p1*app.baseline{t1}+p2*app.baseline{t2};
                    app.threshs{tt} = p1*thresh1+p2*thresh2;

                end
                app.hdataproc{tt} = (app.hdataraw{tt}-app.baseline{tt});
                clayer = NaN(size(app.hdataproc{tt}));
                for ll = 0:numel(app.threshs{tt})-1
                    clayer(isnan(clayer).*(app.hdataproc{tt}<app.threshs{tt}(ll+1))==1)=ll;
                end
                clayer(app.hdataproc{tt}>app.threshs{tt}(end))=numel(app.threshs{tt});
                app.layers{tt} = round(imgaussfilt(clayer,3));
                fracp = (tt+numel(ts))/totp;
                fraci = round(fracp*95);
                app.proc_bar_im(:,1:fraci,1) = 0;
                app.proc_bar_im(:,1:fraci,2) = 1;
                app.ProcessingBar.ImageSource = app.proc_bar_im;
                drawnow
            end
            % Temporal median filter
            medn = 5;
            alllays = [];
            sz = size(app.hdataraw{1});
            for tt = 1:numel(app.hdataraw)
                alllays = cat(3,alllays,app.layers{tt});
            end
            for tt = 1:(medn-1)/2
                alllays = cat(3,alllays,NaN(sz));
                alllays = cat(3,NaN(sz),alllays);
            end
            
            for tt = 1:numel(app.hdataraw)
                
                app.layers{tt} = round(median(alllays(:,:,tt:tt+medn-1),3,'omitnan'));
                
                fracp = (tt+numel(ts)+numel(app.hdataraw))/totp;
                fraci = round(fracp*95);
                app.proc_bar_im(:,1:fraci,1) = 0;
                app.proc_bar_im(:,1:fraci,2) = 1;
                app.ProcessingBar.ImageSource = app.proc_bar_im;
                drawnow
            end
            
            app.processed = true;
            app.OutlinesCheckBox.Value = false;
            plotproc(app);
            plotraw(app);
            
            autorawButtonPushed(app);
            autoprocButtonPushed(app);
            app.ProcessingpleasewaitLabel.Visible = 'off';
            
        end

        % Value changed function: ShowDropDown
        function ShowDropDownValueChanged(app, event)
            value = app.ShowDropDown.Value;
            
            if app.processed
                if app.ShowDropDown.Value == "Zero height"
                    app.processeddataplot.CLim = [0.1*min(min([app.baseline{:}])) 0.9*max(max([app.baseline{:}]))];
                elseif app.ShowDropDown.Value == "Processed height"
                    app.processeddataplot.CLim = [0.1*min(min([app.hdataproc{:}])) 0.9*max(max([app.hdataproc{:}]))];
                elseif app.ShowDropDown.Value == "Layers"
                    app.processeddataplot.CLim = [0.1*min(min([app.layers{:}])) 0.9*max(max([app.layers{:}]))];
                end
                
                app.autoprocButtonPushed(app)
                plotproc(app)
            end
        end

        % Value changed function: OutlinesCheckBox
        function OutlinesCheckBoxValueChanged(app, event)
            value = app.OutlinesCheckBox.Value;
            if value
                layeredges = imdilate(edge(app.layers{app.t}),strel('disk',3));
                app.prochim.AlphaData = ~layeredges;
            else
                app.prochim.AlphaData = ones(768,1024);
            end
        end

        % Value changed function: rawmin
        function rawminValueChanged(app, event)
            value = app.rawmin.Value;
            app.rawheightplot.CLim(1) = str2double(value);
            app.RawCbar.XTickLabel = [0; ...
                str2double(sprintf('%1.2f',0.25*(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.5*(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.75*(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1))))];
            
        end

        % Value changed function: rawmax
        function rawmaxValueChanged(app, event)
            value = app.rawmax.Value;
            app.rawheightplot.CLim(2) = str2double(value);
            app.RawCbar.XTickLabel = [0; ...
                str2double(sprintf('%1.2f',0.25*(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.5*(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.75*(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',(app.rawheightplot.CLim(2)-app.rawheightplot.CLim(1))))];
            
        end

        % Value changed function: procmin
        function procminValueChanged(app, event)
            value = app.procmin.Value;
            app.processeddataplot.CLim(1) = str2double(value);
            app.ProcCbar.XTickLabel = [0; ...
                str2double(sprintf('%1.2f',0.25*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.5*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.75*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1))))];
        end

        % Value changed function: procmax
        function procmaxValueChanged(app, event)
            value = app.procmax.Value;
            
            app.processeddataplot.CLim(2) = str2double(value);
            app.ProcCbar.XTickLabel = [0; ...
                str2double(sprintf('%1.2f',0.25*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.5*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',0.75*(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1)))); ...
                str2double(sprintf('%1.2f',(app.processeddataplot.CLim(2)-app.processeddataplot.CLim(1))))];
        end

        % Button pushed function: autoraw
        function autorawButtonPushed(app, event)
            app.rawmin.Value = sprintf('%1.2f',1.1*min(min([app.hdataraw{:}])));
            app.rawmax.Value = sprintf('%1.2f',0.9*max(max([app.hdataraw{:}])));
            app.rawmaxValueChanged(app);
            app.rawminValueChanged(app);
            
        end

        % Button pushed function: autoproc
        function autoprocButtonPushed(app, event)
            
            if app.ShowDropDown.Value == "Zero height"
                app.procmin.Value = sprintf('%1.2f',1.1*min(min([app.baseline{:}])));
                app.procmax.Value = sprintf('%1.2f',0.9*max(max([app.baseline{:}])));
%                 app.processeddataplot.CLim = [0.1*min(min([app.baseline{:}])) 0.9*max(max([app.baseline{:}]))];
            elseif app.ShowDropDown.Value == "Processed height"
                app.procmin.Value = sprintf('%1.2f',1.1*min(min([app.hdataproc{:}])));
                app.procmax.Value = sprintf('%1.2f',0.9*max(max([app.hdataproc{:}])));
%                 app.processeddataplot.CLim = [0.1*min(min([app.hdataproc{:}])) 0.9*max(max([app.hdataproc{:}]))];
            elseif app.ShowDropDown.Value == "Layers"
                app.procmin.Value = sprintf('%1.2f',1.1*min(min([app.layers{:}])));
                app.procmax.Value = sprintf('%1.2f',0.9*max(max([app.layers{:}])));
%                 app.processeddataplot.CLim = [0.1*min(min([app.layers{:}])) 0.9*max(max([app.layers{:}]))];
            end
            app.procmaxValueChanged(app);
            app.procminValueChanged(app);

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            folder = app.NameEditField.Value;
            fpath = [app.LocationEditField.Value '/'];
            [~,~,~] = mkdir(fpath,[folder '/']);
            
            
            for tt = app.TimeSlider.Limits(1):app.TimeSlider.Limits(2)
                name = sprintf('%06d.bin',tt-1);
                
                fID = fopen([fpath folder '/' name],'w');
                if app.DataDropDown.Value == "Zero height"
                    fwrite(fID,app.baseline{tt},'float');
                elseif app.DataDropDown.Value == "Processed height"
                    fwrite(fID,app.hdataproc{tt},'float');
                elseif app.DataDropDown.Value == "Layers"
                    fwrite(fID,app.layers{tt},'int8');
                end
                
                fclose(fID);
            end
            
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            if value
                tt = app.t;
                while app.PlayButton.Value && tt<=app.TimeSlider.Limits(2)
                    app.PlayButton.Text = ' ▌▌';
                    app.PlayButton.FontSize = 16;

                    pause(0.1)
                    new_t(app,tt);
                    tt = tt+1;
                end
                app.PlayButton.Value = 0;
                app.PlayButton.Text = '►';
                app.PlayButton.FontSize = 24;
            else
                app.PlayButton.Text = '►';
                app.PlayButton.FontSize = 24;
            end
        end

        % Value changed function: time_edit
        function time_editValueChanged(app, event)
            value = str2double(app.time_edit.Value);
            app.new_t(value);
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.new_t(changingValue)
        end

        % Button pushed function: AutopointsButton
        function AutopointsButtonPushed(app, event)
            app.FindingpointsLabel.Visible = 'on';
            app.proc_bar_im(:,:,1) = 1;
            app.proc_bar_im(:,:,2) = 0;
            app.ProcessingBar.ImageSource = app.proc_bar_im;
            drawnow
            sz = size(app.hdataraw{1});
            errn = 0;
            clayers = app.layers{app.t};
            edges = edge(clayers);
            edges = imdilate(edges,strel('disk',20));
            
            while errn<100
                
                newx = 10+(sz(2)-19)*rand(1);
                newy = 10+(sz(1)-19)*rand(1);
                clabels = app.labels([app.labels.t] == app.t);
                dx = [clabels.x]-newx;
                dy = [clabels.y]-newy;
                dr = sqrt(dx.^2+dy.^2);
                if isempty(dr) 
                    dr = 50;
                end
                if min(dr)>30 && edges(round(newy),round(newx))~=1
                    errn = 0;
                    app.labels(end+1).x = newx;
                    app.labels(end).y = newy;
                    app.labels(end).l = clayers(round(newy),round(newx));
                    app.labels(end).t = app.t;
                    updatepts(app);
                    drawnow
                else
                    errn = errn+1;
                end
            end
            app.FindingpointsLabel.Visible = 'off';

        end

        % Value changed function: ColormapEditField
        function ColormapEditFieldValueChanged(app, event)
            app.rawheightplot.Colormap = feval(app.ColormapEditField.Value);
            app.processeddataplot.Colormap = feval(app.ColormapEditField.Value);
            %Make colorbars
            x = 1:512;
            y = 1:20;
            [xx,~] = meshgrid(x,y);
            cbar = real2rgb(xx,feval(app.ColormapEditField.Value),[1 512]);

            image(app.RawCbar,cbar);
            image(app.ProcCbar,cbar);
        end

        % Value changed function: RawheightCheckBox
        function RawheightCheckBoxValueChanged(app, event)
            value = app.RawheightCheckBox.Value;
            try
                plotraw(app);
            end
        end

        % Value changed function: ProcesseddataCheckBox
        function ProcesseddataCheckBoxValueChanged(app, event)
            value = app.ProcesseddataCheckBox.Value;
            try
                plotproc(app);
            end
        end

        % Value changed function: laseralpha
        function laseralphaValueChanged(app, event)
            value = app.laseralpha.Value;
            try
                plotraw(app);
            end
            try
                plotproc(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.rawheightplot = uiaxes(app.GridLayout);
            title(app.rawheightplot, '')
            xlabel(app.rawheightplot, '')
            ylabel(app.rawheightplot, '')
            app.rawheightplot.XTick = [];
            app.rawheightplot.YTick = [];
            app.rawheightplot.Visible = 'off';
            app.processeddataplot = uiaxes(app.GridLayout);
            title(app.processeddataplot, '')
            xlabel(app.processeddataplot, '')
            ylabel(app.processeddataplot, '')
            app.processeddataplot.XTick = [];
            app.processeddataplot.YTick = [];
            app.processeddataplot.Visible = 'off';
            app.SelectButton = uibutton(app.GridLayout, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.LocationEditField = uieditfield(app.GridLayout, 'text');
            app.LocationEditField.Value = '/Users/kcopenhagen/Documents/Data/Monolayer/High frame rate/Data/190111KC2';
            app.TimeSlider = uislider(app.GridLayout);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.RawCbar = uiaxes(app.GridLayout);
            title(app.RawCbar, '')
            xlabel(app.RawCbar, 'Height ({\mu}m)')
            ylabel(app.RawCbar, '')
            app.RawCbar.XLim = [0 512];
            app.RawCbar.XAxisLocation = 'top';
            app.RawCbar.XTick = [0 128 256 384 512];
            app.RawCbar.XTickLabel = {'0'; '0.25'; '0.5'; '0.75'; '1'};
            app.RawCbar.YTick = [];
            app.ProcCbar = uiaxes(app.GridLayout);
            title(app.ProcCbar, '')
            xlabel(app.ProcCbar, 'Height ({\mu}m)')
            ylabel(app.ProcCbar, '')
            app.ProcCbar.XLim = [0 512];
            app.ProcCbar.XAxisLocation = 'top';
            app.ProcCbar.XTick = [0 128 256 384 512];
            app.ProcCbar.XTickLabel = {'0'; '0.25'; '0.5'; '0.75'; '1'};
            app.ProcCbar.YTick = [];
            app.Panel = uipanel(app.GridLayout);
            app.rawmin = uieditfield(app.Panel, 'text');
            app.rawmin.ValueChangedFcn = createCallbackFcn(app, @rawminValueChanged, true);
            app.rawmin.Value = '0';
            app.rawmax = uieditfield(app.Panel, 'text');
            app.rawmax.ValueChangedFcn = createCallbackFcn(app, @rawmaxValueChanged, true);
            app.rawmax.Value = '1';
            app.procmin = uieditfield(app.Panel, 'text');
            app.procmin.ValueChangedFcn = createCallbackFcn(app, @procminValueChanged, true);
            app.procmin.Value = '0';
            app.procmax = uieditfield(app.Panel, 'text');
            app.procmax.ValueChangedFcn = createCallbackFcn(app, @procmaxValueChanged, true);
            app.procmax.Value = '1';
            app.RawLabel = uilabel(app.Panel);
            app.RawLabel.Text = 'Raw:';
            app.ProcessedLabel = uilabel(app.Panel);
            app.ProcessedLabel.Text = 'Processed:';
            app.DisplaysettingsLabel = uilabel(app.Panel);
            app.DisplaysettingsLabel.Text = 'Display settings';
            app.autoraw = uibutton(app.Panel, 'push');
            app.autoraw.ButtonPushedFcn = createCallbackFcn(app, @autorawButtonPushed, true);
            app.autoraw.Text = 'Auto';
            app.autoproc = uibutton(app.Panel, 'push');
            app.autoproc.ButtonPushedFcn = createCallbackFcn(app, @autoprocButtonPushed, true);
            app.autoproc.Text = 'Auto';
            app.ColormapEditFieldLabel = uilabel(app.Panel);
            app.ColormapEditFieldLabel.Text = 'Colormap:';
            app.ColormapEditField = uieditfield(app.Panel, 'text');
            app.ColormapEditField.ValueChangedFcn = createCallbackFcn(app, @ColormapEditFieldValueChanged, true);
            app.ColormapEditField.Value = 'myxocmap';
            app.ShowDropDownLabel = uilabel(app.Panel);
            app.ShowDropDownLabel.Text = 'Show:';
            app.ShowDropDown = uidropdown(app.Panel);
            app.ShowDropDown.Items = {'Zero height', 'Processed height', 'Layers'};
            app.ShowDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowDropDownValueChanged, true);
            app.ShowDropDown.Value = 'Zero height';
            app.OutlinesCheckBox = uicheckbox(app.Panel);
            app.OutlinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @OutlinesCheckBoxValueChanged, true);
            app.OutlinesCheckBox.Text = 'Outlines';
            app.HeightlimitsmLabel = uilabel(app.Panel);
            app.HeightlimitsmLabel.Text = 'Height limits (µm)';
            app.HeightmapsurfacecorrectionLabel = uilabel(app.GridLayout);
            app.HeightmapsurfacecorrectionLabel.Text = 'Height map surface correction.';
            app.Panel_2 = uipanel(app.GridLayout);
            app.ExportdataLabel = uilabel(app.Panel_2);
            app.ExportdataLabel.Text = 'Export data';
            app.NameEditFieldLabel = uilabel(app.Panel_2);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.Panel_2, 'text');
            app.DataDropDownLabel = uilabel(app.Panel_2);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.Panel_2);
            app.DataDropDown.Items = {'Zero height', 'Processed height', 'Layers'};
            app.DataDropDown.Value = 'Zero height';
            app.ExportButton = uibutton(app.Panel_2, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.PlayButton = uibutton(app.GridLayout, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = '►';
            app.AutopointsButton = uibutton(app.GridLayout, 'push');
            app.AutopointsButton.ButtonPushedFcn = createCallbackFcn(app, @AutopointsButtonPushed, true);
            app.AutopointsButton.Text = 'Auto points';
            app.Panel_4 = uipanel(app.GridLayout);
            app.ProcessdataButton = uibutton(app.Panel_4, 'push');
            app.ProcessdataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessdataButtonPushed, true);
            app.ProcessdataButton.Text = 'Process data';
            app.ProcessingBar = uiimage(app.Panel_4);
            app.ProcessingpleasewaitLabel = uilabel(app.Panel_4);
            app.ProcessingpleasewaitLabel.Visible = 'off';
            app.ProcessingpleasewaitLabel.Text = 'Processing please wait...';
            app.Panel_5 = uipanel(app.GridLayout);
            app.LoaddataButton = uibutton(app.Panel_5, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.LoadingBar = uiimage(app.Panel_5);
            app.LoadingpleasewaitLabel = uilabel(app.Panel_5);
            app.LoadingpleasewaitLabel.Visible = 'off';
            app.LoadingpleasewaitLabel.Text = 'Loading please wait...';
            app.SmoothLabel = uilabel(app.Panel_5);
            app.SmoothLabel.Text = 'Smooth, σ=';
            app.SigmaEdit = uieditfield(app.Panel_5, 'numeric');
            app.TimePanel = uipanel(app.GridLayout);
            app.TimePanel.Title = 'Time:';
            app.time_edit = uieditfield(app.TimePanel, 'text');
            app.time_edit.ValueChangedFcn = createCallbackFcn(app, @time_editValueChanged, true);
            app.time_edit.Value = '1';
            app.FindingpointsLabel = uilabel(app.GridLayout);
            app.FindingpointsLabel.Visible = 'off';
            app.FindingpointsLabel.Text = 'Finding points...';
            app.Panel_6 = uipanel(app.GridLayout);
            app.RawheightCheckBox = uicheckbox(app.Panel_6);
            app.RawheightCheckBox.ValueChangedFcn = createCallbackFcn(app, @RawheightCheckBoxValueChanged, true);
            app.RawheightCheckBox.Text = 'Raw height';
            app.LaserOverlayLabel = uilabel(app.Panel_6);
            app.LaserOverlayLabel.Text = 'Laser Overlay';
            app.ProcesseddataCheckBox = uicheckbox(app.Panel_6);
            app.ProcesseddataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcesseddataCheckBoxValueChanged, true);
            app.ProcesseddataCheckBox.Text = 'Processed data';
            app.Label = uilabel(app.Panel_6);
            app.Label.Text = 'α = ';
            app.laseralpha = uieditfield(app.Panel_6, 'numeric');
            app.laseralpha.ValueChangedFcn = createCallbackFcn(app, @laseralphaValueChanged, true);
            app.laseralpha.Value = 1;
            app.to1Label = uilabel(app.Panel_6);
            app.to1Label.Text = '(0 to 1)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end